classdef move_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PositionandJointconfigButton  matlab.ui.control.Button
        Q7Slider                      matlab.ui.control.Slider
        Q7SliderLabel                 matlab.ui.control.Label
        Q6Slider                      matlab.ui.control.Slider
        Q6SliderLabel                 matlab.ui.control.Label
        Q5Slider                      matlab.ui.control.Slider
        Q5SliderLabel                 matlab.ui.control.Label
        Q4Slider                      matlab.ui.control.Slider
        Q4SliderLabel                 matlab.ui.control.Label
        Q3Slider                      matlab.ui.control.Slider
        Q3SliderLabel                 matlab.ui.control.Label
        Q2Slider                      matlab.ui.control.Slider
        Q2SliderLabel                 matlab.ui.control.Label
        Q1Slider                      matlab.ui.control.Slider
        Q1SliderLabel                 matlab.ui.control.Label
        ERESETButton                  matlab.ui.control.Button
        zplus                         matlab.ui.control.Button
        zminus                        matlab.ui.control.Button
        RobotSelectDropDown           matlab.ui.control.DropDown
        RobotSelectDropDownLabel      matlab.ui.control.Label
        xplusButton                   matlab.ui.control.Button
        xminusButton                  matlab.ui.control.Button
        yminus                        matlab.ui.control.Button
        yplus                         matlab.ui.control.Button
        Button                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        StopCheck = false;
        UR3
        UR3Grip
        KUKA
        KUKAGrip
        RobotSelect = 'UR3'
        reset = false;
        qslider = [0 0 0 0 0 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.StopCheck == false
                app.StopCheck = true;
            elseif app.reset == true
                app.StopCheck = false;
                app.reset = false;
            end
        end

        % Button pushed function: xplusButton
        function xplusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(1,4) = tr(1,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(1,4) = tr(1,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Button pushed function: xminusButton
        function xminusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(1,4) = tr(1,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(1,4) = tr(1,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Button pushed function: yplus
        function yplusPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(2,4) = tr(2,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(2,4) = tr(2,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Button pushed function: yminus
        function yminusPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(2,4) = tr(2,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(2,4) = tr(2,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Drop down opening function: RobotSelectDropDown
        function RobotSelectDropDownOpening(app, event)
            
        end

        % Value changed function: RobotSelectDropDown
        function RobotSelectDropDownValueChanged(app, event)
            app.RobotSelect = app.RobotSelectDropDown.Value;
            
        end

        % Button pushed function: zplus
        function zplusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(3,4) = tr(3,4)+0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(3,4) = tr(3,4)+0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Button pushed function: zminus
        function zminusButtonPushed(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'UR3'
                        q = app.UR3.getpos;
                        tr = app.UR3.fkine(q);
                        tr(3,4) = tr(3,4)-0.01;
                        newQ = app.UR3.ikcon(tr,q);
                        app.UR3.animate(newQ);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'KUKA'
                        q = app.KUKA.getpos;
                        tr = app.KUKA.fkine(q);
                        tr(3,4) = tr(3,4)-0.01;
                        newQ = app.KUKA.ikcon(tr,q);
                        app.KUKA.animate(newQ);
                        gripperAnimate(app.KUKAGrip, tr);
                end
                
            end
        end

        % Button pushed function: ERESETButton
        function ERESETButtonPushed(app, event)
            if app.StopCheck == true
                app.reset = true;
            end
        end

        % Value changed function: Q1Slider
        function Q1SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q1slide = app.Q1Slider.Value/100;
                        q1 = app.KUKA.qlim(1,1)+q1slide*(app.KUKA.qlim(1,2)-app.KUKA.qlim(1,1));
                        q = app.KUKA.getpos;
                        q(1,1) = q1;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q1slide = app.Q1Slider.Value/100;
                        q1 = app.UR3.qlim(1,1)+q1slide*(app.UR3.qlim(1,2)-app.UR3.qlim(1,1));
                        q = app.UR3.getpos;
                        q(1,1) = q1;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
            
            
        end

        % Value changed function: Q2Slider
        function Q2SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q2slide = app.Q2Slider.Value/100;
                        q2 = app.KUKA.qlim(2,1)+q2slide*(app.KUKA.qlim(2,2)-app.KUKA.qlim(2,1));
                        q = app.KUKA.getpos;
                        q(1,2) = q2;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q2slide = app.Q2Slider.Value/100;
                        q2 = app.UR3.qlim(2,1)+q2slide*(app.UR3.qlim(2,2)-app.UR3.qlim(2,1));
                        q = app.UR3.getpos;
                        q(1,2) = q2;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
        end

        % Value changed function: Q3Slider
        function Q3SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q3slide = app.Q3Slider.Value/100;
                        q3 = app.KUKA.qlim(3,1)+q3slide*(app.KUKA.qlim(3,2)-app.KUKA.qlim(3,1));
                        q = app.KUKA.getpos;
                        q(1,3) = q3;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q3slide = app.Q3Slider.Value/100;
                        q3 = app.UR3.qlim(3,1)+q3slide*(app.UR3.qlim(3,2)-app.UR3.qlim(3,1));
                        q = app.UR3.getpos;
                        q(1,3) = q3;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
            
        end

        % Value changed function: Q4Slider
        function Q4SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q4slide = app.Q4Slider.Value/100;
                        q4 = app.KUKA.qlim(4,1)+q4slide*(app.KUKA.qlim(4,2)-app.KUKA.qlim(4,1));
                        q = app.KUKA.getpos;
                        q(1,4) = q4;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q4slide = app.Q4Slider.Value/100;
                        q4 = app.UR3.qlim(4,1)+q4slide*(app.UR3.qlim(4,2)-app.UR3.qlim(4,1));
                        q = app.UR3.getpos;
                        q(1,4) = q4;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
            
        end

        % Value changed function: Q5Slider
        function Q5SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q5slide = app.Q5Slider.Value/100;
                        q5 = app.KUKA.qlim(5,1)+q5slide*(app.KUKA.qlim(5,2)-app.KUKA.qlim(5,1));
                        q = app.KUKA.getpos;
                        q(1,5) = q5;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q5slide = app.Q5Slider.Value/100;
                        q5 = app.UR3.qlim(5,1)+q5slide*(app.UR3.qlim(5,2)-app.UR3.qlim(5,1));
                        q = app.UR3.getpos;
                        q(1,5) = q5;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
        end

        % Value changed function: Q6Slider
        function Q6SliderValueChanged(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q6slide = app.Q6Slider.Value/100;
                        q6 = app.KUKA.qlim(6,1)+q6slide*(app.KUKA.qlim(6,2)-app.KUKA.qlim(6,1));
                        q = app.KUKA.getpos;
                        q(1,6) = q6;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                    case 'UR3'
                        q6slide = app.Q6Slider.Value/100;
                        q6 = app.UR3.qlim(6,1)+q6slide*(app.UR3.qlim(6,2)-app.UR3.qlim(6,1));
                        q = app.UR3.getpos;
                        q(1,6) = q6;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
        end

        % Value changing function: Q7Slider
        function Q7SliderValueChanging(app, event)
            if app.StopCheck == false
                switch app.RobotSelect
                    case 'KUKA'
                        q7slide = app.Q7Slider.Value/100;
                        q7 = app.KUKA.qlim(7,1)+q7slide*(app.KUKA.qlim(7,2)-app.KUKA.qlim(7,1));
                        q = app.KUKA.getpos;
                        q(1,7) = q7;
                        app.KUKA.animate(q);
                        tr = app.KUKA.fkine(q);
                        gripperAnimate(app.KUKAGrip, tr);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                    case 'UR3'
                        q7slide = app.Q7Slider.Value/100;
                        q7 = app.UR3.qlim(7,1)+q7slide*(app.UR3.qlim(7,2)-app.UR3.qlim(7,1));
                        q = app.UR3.getpos;
                        q(1,7) = q7;
                        app.UR3.animate(q);
                        tr = app.UR3.fkine(q);
                        app.UR3Grip.base = tr * trotx(pi) * transl(0,0,-0.11);
                        app.UR3Grip.animate(0);
                end
            end
            
        end

        % Button pushed function: PositionandJointconfigButton
        function PositionandJointconfigButtonPushed(app, event)
            switch app.RobotSelect
                case 'KUKA'
                    q = app.KUKA.getpos;
                    T = app.KUKA.fkine(q);
                    xyz = T(1:3,4);
                    formatSpec = 'KUKA Position\nX Y Z = %.4f %.4f %.4f \nJoint Position %.4f %.4f %.4f %.4f %.4f %.4f %.4f';
                    sprintf(formatSpec,xyz(1),xyz(2),xyz(3),q(1),q(2),q(3),q(4),q(5),q(6),q(7))
                case 'UR3'
                    q = app.UR3.getpos;
                    T = app.UR3.fkine(q);
                    xyz = T(1:3,4);
                    formatSpec = 'UR3 Position\nX Y Z = %.4f %.4f %.4f \nJoint Position %.4f %.4f %.4f %.4f %.4f %.4f %.4f';
                    sprintf(formatSpec,xyz(1),xyz(2),xyz(3),q(1),q(2),q(3),q(4),q(5),q(6))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'ESTOP.jpg';
            app.Button.Text = '';
            app.yplus = uibutton(app.UIFigure, 'push');
            app.yplus.ButtonPushedFcn = createCallbackFcn(app, @yplusPushed, true);
            app.yplus.Text = '+y';
            app.yminus = uibutton(app.UIFigure, 'push');
            app.yminus.ButtonPushedFcn = createCallbackFcn(app, @yminusPushed, true);
            app.yminus.Text = '-y';
            app.xminusButton = uibutton(app.UIFigure, 'push');
            app.xminusButton.ButtonPushedFcn = createCallbackFcn(app, @xminusButtonPushed, true);
            app.xminusButton.Text = {'-x'; ''};
            app.xplusButton = uibutton(app.UIFigure, 'push');
            app.xplusButton.ButtonPushedFcn = createCallbackFcn(app, @xplusButtonPushed, true);
            app.xplusButton.Text = '+x';
            app.RobotSelectDropDownLabel = uilabel(app.UIFigure);
            app.RobotSelectDropDownLabel.Text = 'Robot Select';
            app.RobotSelectDropDown = uidropdown(app.UIFigure);
            app.RobotSelectDropDown.Items = {'UR3', 'KUKA'};
            app.RobotSelectDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RobotSelectDropDownOpening, true);
            app.RobotSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotSelectDropDownValueChanged, true);
            app.RobotSelectDropDown.Value = 'UR3';
            app.zminus = uibutton(app.UIFigure, 'push');
            app.zminus.ButtonPushedFcn = createCallbackFcn(app, @zminusButtonPushed, true);
            app.zminus.Text = '-z';
            app.zplus = uibutton(app.UIFigure, 'push');
            app.zplus.ButtonPushedFcn = createCallbackFcn(app, @zplusButtonPushed, true);
            app.zplus.Text = '+z';
            app.ERESETButton = uibutton(app.UIFigure, 'push');
            app.ERESETButton.ButtonPushedFcn = createCallbackFcn(app, @ERESETButtonPushed, true);
            app.ERESETButton.Text = 'E-RESET';
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1 Slider';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.MajorTicks = [0 100];
            app.Q1Slider.MajorTickLabels = {'0%', '100%'};
            app.Q1Slider.ValueChangedFcn = createCallbackFcn(app, @Q1SliderValueChanged, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2 Slider';
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.MajorTicks = [0 100];
            app.Q2Slider.MajorTickLabels = {'0%', '100%'};
            app.Q2Slider.ValueChangedFcn = createCallbackFcn(app, @Q2SliderValueChanged, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3 Slider';
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.MajorTicks = [0 100];
            app.Q3Slider.MajorTickLabels = {'0%', '100%'};
            app.Q3Slider.ValueChangedFcn = createCallbackFcn(app, @Q3SliderValueChanged, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4 Slider';
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.MajorTicks = [0 100];
            app.Q4Slider.MajorTickLabels = {'0%', '100%'};
            app.Q4Slider.ValueChangedFcn = createCallbackFcn(app, @Q4SliderValueChanged, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5 Slider';
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.MajorTicks = [0 100];
            app.Q5Slider.MajorTickLabels = {'0%', '100%'};
            app.Q5Slider.ValueChangedFcn = createCallbackFcn(app, @Q5SliderValueChanged, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6 Slider';
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.MajorTicks = [0 100];
            app.Q6Slider.MajorTickLabels = {'0%', '100%'};
            app.Q6Slider.ValueChangedFcn = createCallbackFcn(app, @Q6SliderValueChanged, true);
            app.Q7SliderLabel = uilabel(app.UIFigure);
            app.Q7SliderLabel.Text = 'Q7 Slider';
            app.Q7Slider = uislider(app.UIFigure);
            app.Q7Slider.MajorTicks = [0 100];
            app.Q7Slider.MajorTickLabels = {'0%', '100%'};
            app.Q7Slider.ValueChangingFcn = createCallbackFcn(app, @Q7SliderValueChanging, true);
            app.PositionandJointconfigButton = uibutton(app.UIFigure, 'push');
            app.PositionandJointconfigButton.ButtonPushedFcn = createCallbackFcn(app, @PositionandJointconfigButtonPushed, true);
            app.PositionandJointconfigButton.Text = 'Position and Joint config';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = move_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end